classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConnessioneUIFigure            matlab.ui.Figure
        ImportazionedadatabasePanel_2  matlab.ui.container.Panel
        ConnessioneLampLabel           matlab.ui.control.Label
        ConnessioneLamp                matlab.ui.control.Lamp
        ConnettiButton                 matlab.ui.control.Button
        ChiudiButton                   matlab.ui.control.Button
        ImpostazioniButton             matlab.ui.control.Button
        CollectiondisponibiliDropDownLabel  matlab.ui.control.Label
        collectionDisp                 matlab.ui.control.DropDown
        ImportaButton                  matlab.ui.control.Button
        SalvalasessioneinlocaleunavoltaimportataCheckBox  matlab.ui.control.CheckBox
        SessionidisponibiliLabel       matlab.ui.control.Label
        sessioniDisp                   matlab.ui.control.DropDown
        StatodatiPanel                 matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        NdocumentiLabel                matlab.ui.control.Label
        SessionutilizzataLabel         matlab.ui.control.Label
        DatiimportatiLampLabel         matlab.ui.control.Label
        DatiimportatiLamp              matlab.ui.control.Lamp
        coll                           matlab.ui.control.Label
        doc                            matlab.ui.control.Label
        RiepilogodatiLabel             matlab.ui.control.Label
        TemporichiestoLabel            matlab.ui.control.Label
        timer                          matlab.ui.control.Label
        PulisciButton                  matlab.ui.control.Button
        ImportazionedalocalePanel      matlab.ui.container.Panel
        FileselezionatoLabel           matlab.ui.control.Label
        SelezionaButton                matlab.ui.control.Button
        ImportaButton_2                matlab.ui.control.Button
        selezionatoLabel               matlab.ui.control.Label
    end

    %Global variables
    properties (Access = public)
        conn; %Database connection
        collectionSel;
        collection;
        d;
        mongoqry;
        mongoprj;
        file;
        sessions;
    end
    
    properties (Access = private)
        settingApp; % Description
        graphicApp;
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ConnettiMenuSelected(app, event)
            %CONNECTION
            %connection variables
            load settings.mat settings;
            host=settings(1,2);
            port=uint16(str2double(settings(2,2)));
            database=settings(3,2);
            usr=setting(4,2);
            pwd=settings(5,2);
            
            %connection function
            app.conn = mongo(host,port,database,'UserName',usr,'Password',pwd);
            if isopen(app.conn)%connection check TO IMPROVE
                app.ConnessioneLamp.Color=[0.00,1.00,0.00];
            end 
            
        end

        % Callback function
        function ChiudiMenuSelected(app, event)
            close(app.conn)
            if ~isopen(app.conn)%connection check TO IMPROVE
                app.ConnessioneLamp.Color=[1.00,0.00,0.00];
            end
        end

        % Button pushed function: ConnettiButton
        function ConnettiButtonPushed(app, event)
            %CONNECTION
            %connection variables
%             host="repos.dii.unitn.it";
%             port=27017;
%             database="eagle_test";
%             usr="eagle";
%             pwd="E-Agle2020";
            
            %connection function
            load settings.mat settings;
            host=settings(1,2);
            port=uint16(str2double(settings(2,2)));
            database=settings(3,2);
            usr=settings(4,2);
            pwd=settings(5,2);
            app.conn = mongo(host,port,database,'UserName',usr,'Password',pwd);
            if isopen(app.conn)%connection check TO IMPROVE
                app.ConnessioneLamp.Color=[0.00,1.00,0.00];
            end 
            collections=[" "];
            collections=[collections; app.conn.CollectionNames];
            app.collectionDisp.Items=collections;
%             app.ImportaButton.Enable=true;
            app.ChiudiButton.Enable=true;
            app.ConnettiButton.Enable=false;
            %AGGIUNGERE SCHERMATA DI ERRORE
        end

        % Button pushed function: ImportaButton
        function ImportaButtonPushed(app, event)
            tic
            %IMPORT DATA
            LIMITE=1000;%package dimension
            
            %query parameters
            q = char(39);
            app.mongoqry=strcat("{sessionName : ",q,app.sessioniDisp.Value,q,", id :{$gt: 0 }}");%selection
            app.mongoprj="";%projection
            
            %import loop
            app.collection=[];
            n=0;
            chk=1;
            while chk
                query=find(app.conn,app.collectionDisp.Value,'Projection',app.mongoprj,'Query',app.mongoqry,'Skip',n,'Limit',LIMITE);
                if ~isempty(query)
                    app.collection = [app.collection;query];
                    n=n+LIMITE;
                else
                    chk=0;
                end
            end
            clkTimer=toc;
            %delete(app.graphicApp);
            app.graphicApp = grafico(app,'db',app.collection,"",app.SalvalasessioneinlocaleunavoltaimportataCheckBox.Value,app.sessioniDisp.Value); 
            app.d=size(app.collection);
            app.DatiimportatiLamp.Color=[0.00,1.00,0.00];
            app.PulisciButton.Enable=true;
            app.coll.Text=app.sessioniDisp.Value;
            app.doc.Text=int2str(app.d(1,1));
            app.timer.Text=strcat(int2str(clkTimer)," s");
        end

        % Button pushed function: ChiudiButton
        function ChiudiButtonPushed2(app, event)
            close(app.conn)
            if ~isopen(app.conn)%connection check TO IMPROVE
                app.ConnessioneLamp.Color=[1.00,0.00,0.00];
                app.ImportaButton.Enable=false;
                app.ConnettiButton.Enable=true;
                app.ChiudiButton.Enable=false;
            end
            %AGGIUNGERE PULIZIA CAMPI
        end

        % Button pushed function: PulisciButton
        function PulisciButtonPushed(app, event)
            app.collection=[];
            app.d=size(app.collection);
            app.coll.Text="";
            app.doc.Text=int2str(app.d(1,1));
            app.timer.Text="";
            app.DatiimportatiLamp.Color=[1.00,0.00,0.00];
            app.PulisciButton.Enable=false;
            app.ImportaButton.Enable=true;
        end

        % Button pushed function: ImpostazioniButton
        function ImpostazioniButtonPushed(app, event)
            app.ImpostazioniButton.Enable=false;
            app.settingApp = app2(app);
        end

        % Close request function: ConnessioneUIFigure
        function ConnessioneUIFigureCloseRequest(app, event)
            delete(app.settingApp)
            delete(app.graphicApp)
            delete(app)
        end

        % Button pushed function: SelezionaButton
        function SelezionaButtonPushed(app, event)
            app.file=uigetfile('*.mat','Seleziona file');
            if isequal(app.file,0)
                app.selezionatoLabel.Text="";
                uialert(app.ConnessioneUIFigure,'Nessun file selezionato','Errore');
                app.ImportaButton_2.Enable=false;
            else
                app.selezionatoLabel.Text=app.file;
                app.ImportaButton_2.Enable=true;
            end
        end

        % Button pushed function: ImportaButton_2
        function ImportaButton_2Pushed(app, event)
            app.graphicApp = grafico(app,'local',"",app.file,0,"");%VERIFICARE 
            %LOAD HEADER
            tic
            load(app.file,'-mat','sessionName','dim')
            app.DatiimportatiLamp.Color=[0.00,1.00,0.00];
            app.PulisciButton.Enable=true;
            app.coll.Text=sessionName;
            app.doc.Text=int2str(dim(1,1));
            app.timer.Text=strcat(int2str(toc)," s");
        end

        % Value changed function: collectionDisp
        function collectionDispValueChanged(app, event)
            value = app.collectionDisp.Value;
            if (value~=" ")
                field = "sessionName";
                app.sessions = distinct(app.conn,value,field);
                app.sessioniDisp.Items=app.sessions;
            end
            app.ImportaButton.Enable=true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConnessioneUIFigure = uifigure('Visible', 'off');
            app.ConnessioneUIFigure.Name = 'Connessione';
            app.ConnessioneUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConnessioneUIFigureCloseRequest, true);
            app.ImportazionedadatabasePanel_2 = uipanel(app.ConnessioneUIFigure);
            app.ImportazionedadatabasePanel_2.Title = 'Importazione da database';
            app.ConnessioneLampLabel = uilabel(app.ImportazionedadatabasePanel_2);
            app.ConnessioneLampLabel.Text = 'Connessione';
            app.ConnessioneLamp = uilamp(app.ImportazionedadatabasePanel_2);
            app.ConnettiButton = uibutton(app.ImportazionedadatabasePanel_2, 'push');
            app.ConnettiButton.ButtonPushedFcn = createCallbackFcn(app, @ConnettiButtonPushed, true);
            app.ConnettiButton.Text = 'Connetti';
            app.ChiudiButton = uibutton(app.ImportazionedadatabasePanel_2, 'push');
            app.ChiudiButton.ButtonPushedFcn = createCallbackFcn(app, @ChiudiButtonPushed2, true);
            app.ChiudiButton.Enable = 'off';
            app.ChiudiButton.Text = 'Chiudi';
            app.ImpostazioniButton = uibutton(app.ImportazionedadatabasePanel_2, 'push');
            app.ImpostazioniButton.ButtonPushedFcn = createCallbackFcn(app, @ImpostazioniButtonPushed, true);
            app.ImpostazioniButton.Text = 'Impostazioni';
            app.CollectiondisponibiliDropDownLabel = uilabel(app.ImportazionedadatabasePanel_2);
            app.CollectiondisponibiliDropDownLabel.Text = 'Collection disponibili';
            app.collectionDisp = uidropdown(app.ImportazionedadatabasePanel_2);
            app.collectionDisp.Items = {};
            app.collectionDisp.ValueChangedFcn = createCallbackFcn(app, @collectionDispValueChanged, true);
            app.collectionDisp.Value = {};
            app.ImportaButton = uibutton(app.ImportazionedadatabasePanel_2, 'push');
            app.ImportaButton.ButtonPushedFcn = createCallbackFcn(app, @ImportaButtonPushed, true);
            app.ImportaButton.Enable = 'off';
            app.ImportaButton.Text = 'Importa';
            app.SalvalasessioneinlocaleunavoltaimportataCheckBox = uicheckbox(app.ImportazionedadatabasePanel_2);
            app.SalvalasessioneinlocaleunavoltaimportataCheckBox.Text = 'Salva la sessione in locale una volta importata';
            app.SalvalasessioneinlocaleunavoltaimportataCheckBox.Value = true;
            app.SessionidisponibiliLabel = uilabel(app.ImportazionedadatabasePanel_2);
            app.SessionidisponibiliLabel.Text = 'Sessioni disponibili';
            app.sessioniDisp = uidropdown(app.ImportazionedadatabasePanel_2);
            app.sessioniDisp.Items = {};
            app.sessioniDisp.Value = {};
            app.StatodatiPanel = uipanel(app.ConnessioneUIFigure);
            app.StatodatiPanel.Title = 'Stato dati';
            app.GridLayout = uigridlayout(app.StatodatiPanel);
            app.NdocumentiLabel = uilabel(app.GridLayout);
            app.NdocumentiLabel.Text = 'N. documenti:';
            app.SessionutilizzataLabel = uilabel(app.GridLayout);
            app.SessionutilizzataLabel.Text = 'Session utilizzata:';
            app.DatiimportatiLampLabel = uilabel(app.GridLayout);
            app.DatiimportatiLampLabel.Text = 'Dati importati';
            app.DatiimportatiLamp = uilamp(app.GridLayout);
            app.coll = uilabel(app.GridLayout);
            app.coll.Text = '';
            app.doc = uilabel(app.GridLayout);
            app.doc.Text = '';
            app.RiepilogodatiLabel = uilabel(app.GridLayout);
            app.RiepilogodatiLabel.Text = 'Riepilogo dati:';
            app.TemporichiestoLabel = uilabel(app.GridLayout);
            app.TemporichiestoLabel.Text = 'Tempo richiesto:';
            app.timer = uilabel(app.GridLayout);
            app.timer.Text = '';
            app.PulisciButton = uibutton(app.GridLayout, 'push');
            app.PulisciButton.ButtonPushedFcn = createCallbackFcn(app, @PulisciButtonPushed, true);
            app.PulisciButton.Enable = 'off';
            app.PulisciButton.Text = 'Pulisci';
            app.ImportazionedalocalePanel = uipanel(app.ConnessioneUIFigure);
            app.ImportazionedalocalePanel.Title = 'Importazione da locale';
            app.FileselezionatoLabel = uilabel(app.ImportazionedalocalePanel);
            app.FileselezionatoLabel.Text = 'File selezionato:';
            app.SelezionaButton = uibutton(app.ImportazionedalocalePanel, 'push');
            app.SelezionaButton.ButtonPushedFcn = createCallbackFcn(app, @SelezionaButtonPushed, true);
            app.SelezionaButton.Text = 'Seleziona';
            app.ImportaButton_2 = uibutton(app.ImportazionedalocalePanel, 'push');
            app.ImportaButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportaButton_2Pushed, true);
            app.ImportaButton_2.Enable = 'off';
            app.ImportaButton_2.Text = 'Importa';
            app.selezionatoLabel = uilabel(app.ImportazionedalocalePanel);
            app.selezionatoLabel.Text = '';
            % Show the figure after all components are created
            app.ConnessioneUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConnessioneUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConnessioneUIFigure)
        end
    end
end