classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        HostEditFieldLabel      matlab.ui.control.Label
        HostEditField           matlab.ui.control.EditField
        PortEditFieldLabel      matlab.ui.control.Label
        PortEditField           matlab.ui.control.EditField
        UsernameLabel           matlab.ui.control.Label
        UsernameEditField       matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        PasswordEditField       matlab.ui.control.EditField
        DatabaseEditFieldLabel  matlab.ui.control.Label
        DatabaseEditField       matlab.ui.control.EditField
        SalvaButton             matlab.ui.control.Button
        PuliscicampiButton      matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.CallingApp = mainapp;
        
            % Process sz and c inputs
            if isfile("settings.mat")
                load settings.mat settings;
                app.HostEditField.Value=settings(1,2);
                app.PortEditField.Value=settings(2,2);
                app.UsernameEditField.Value=settings(4,2);
                app.PasswordEditField.Value=settings(5,2);
                app.DatabaseEditField.Value=settings(3,2);
            else
                edit("settings.mat");
                settings=["host" "";"port" "";"db" "";"user" "";"password" ""];
                save settings.mat settings;
            end
            
        end

        % Button pushed function: PuliscicampiButton
        function PuliscicampiButtonPushed(app, event)
            app.HostEditField.Value="";
            app.PortEditField.Value="";
            app.UsernameEditField.Value="";
            app.PasswordEditField.Value="";
            app.DatabaseEditField.Value=""; 
            app.SalvaButton.Enable=false;
        end

        % Button pushed function: SalvaButton
        function SalvaButtonPushed(app, event)
            settings=["host" app.HostEditField.Value;"port" app.PortEditField.Value;"db" app.DatabaseEditField.Value;"user" app.UsernameEditField.Value;"password" app.PasswordEditField.Value];
            save settings.mat settings;
            app.CallingApp.ImpostazioniButton.Enable=true;
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            load settings.mat settings;
            app.HostEditField.Value=settings(1,2);
            app.PortEditField.Value=settings(2,2);
            app.UsernameEditField.Value=settings(4,2);
            app.PasswordEditField.Value=settings(5,2);
            app.DatabaseEditField.Value=settings(3,2);
            app.SalvaButton.Enable=true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ImpostazioniButton.Enable=true;
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HostEditFieldLabel = uilabel(app.UIFigure);
            app.HostEditFieldLabel.Text = 'Host:';
            app.HostEditField = uieditfield(app.UIFigure, 'text');
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port:';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            app.UsernameLabel = uilabel(app.UIFigure);
            app.UsernameLabel.Text = 'Username:';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.DatabaseEditFieldLabel = uilabel(app.UIFigure);
            app.DatabaseEditFieldLabel.Text = 'Database';
            app.DatabaseEditField = uieditfield(app.UIFigure, 'text');
            app.SalvaButton = uibutton(app.UIFigure, 'push');
            app.SalvaButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaButtonPushed, true);
            app.SalvaButton.Text = 'Salva';
            app.PuliscicampiButton = uibutton(app.UIFigure, 'push');
            app.PuliscicampiButton.ButtonPushedFcn = createCallbackFcn(app, @PuliscicampiButtonPushed, true);
            app.PuliscicampiButton.Text = 'Pulisci campi';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end