classdef filtra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FiltraUIFigure        matlab.ui.Figure
        MarkerEditFieldLabel  matlab.ui.control.Label
        MarkerEditField       matlab.ui.control.NumericEditField
        MinSliderLabel        matlab.ui.control.Label
        MinSlider             matlab.ui.control.Slider
        MaxSliderLabel        matlab.ui.control.Label
        MaxSlider             matlab.ui.control.Slider
        TimestampLabel        matlab.ui.control.Label
        minTmField            matlab.ui.control.NumericEditField
        maxTmField            matlab.ui.control.NumericEditField
        SalvaButton           matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp
        minMark
        maxMark
        minTime
        maxTime
        markerLimits
        minTimeLimits
        maxTimeLimits
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, minMark, maxMark, minTime, maxTime)
            app.mainApp=mainApp;
            app.minMark=minMark;
            app.maxMark=maxMark;
            app.minTime=minTime;
            app.maxTime=maxTime;
            app.markerLimits=[app.minMark app.maxMark];
            app.minTimeLimits=[app.minTime app.maxTime-1];
            app.maxTimeLimits=[app.minTime+1 app.maxTime];
            app.minTmField.Limits=app.minTimeLimits;
            app.maxTmField.Limits=app.maxTimeLimits;
            app.MaxSlider.Limits=app.maxTimeLimits;
            app.MinSlider.Limits=app.minTimeLimits;
            app.MaxSlider.Value=app.maxTime;
            app.MinSlider.Value=app.minTime;
            app.maxTmField=app.maxTime;
            app.minTmField=app.MinSlider.Value;
            
        end

        % Value changing function: MinSlider
        function MinSliderValueChanging(app, event)
            changingValue = event.Value;
            app.minTmField.Value=changingValue;
            app.MaxSlider.Limits=[changingValue+1 app.maxTime];
            app.maxTmField.Value=app.MaxSlider.Value;
        end

        % Close request function: FiltraUIFigure
        function FiltraUIFigureCloseRequest(app, event)
            app.mainApp.FiltraButton_2.Enable=true;
            delete(app)
        end

        % Value changing function: MaxSlider
        function MaxSliderValueChanging(app, event)
            changingValue = event.Value;
            app.maxTmField.Value=changingValue;
            app.MinSlider.Limits=[app.minTime changingValue-1];
            app.minTmField.Value=app.MinSlider.Value;
        end

        % Value changed function: minTmField
        function minTmFieldValueChanged(app, event)
            value = app.minTmField.Value;
            if value<=app.MinSlider.Limits(1,2) 
                app.MinSlider.Value=value;
                app.MaxSlider.Limits=[value+1 app.maxTime];
                app.maxTmField.Value=app.MaxSlider.Value;
            else
                uialert(app.ConnessioneUIFigure,'Il valore è maggiore del valore massimo','Errore');
            end
        end

        % Value changed function: maxTmField
        function maxTmFieldValueChanged(app, event)
            value = app.maxTmField.Value;
            if value>=app.MaxSlider.Limits(1,1) 
                app.MaxSlider.Value=value;
                app.MinSlider.Limits=[app.minTime value-1];
                app.minTmField.Value=app.MinSlider.Value;
            else
                uialert(app.ConnessioneUIFigure,'Il valore è minore del valore minimo','Errore');
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.minTmField.Limits=app.minTimeLimits;
            app.maxTmField.Limits=app.maxTimeLimits;
            app.MaxSlider.Limits=app.maxTimeLimits;
            app.MinSlider.Limits=app.minTimeLimits;
            app.MaxSlider.Value=app.maxTime;
            app.MinSlider.Value=app.minTime;
            app.maxTmField=app.maxTime;
            app.minTmField=app.minTime;
        end

        % Button pushed function: SalvaButton
        function SalvaButtonPushed(app, event)
            app.mainApp.markerFlt=app.MarkerEditField.Value;
            app.mainApp.minTmFlt=app.minTmField.Value;
            app.mainApp.maxTmFlt=app.maxTmField;
            app.mainApp.filtra(app.mainApp);
            app.mainApp.FiltraButton_2.Enable=true;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FiltraUIFigure = uifigure('Visible', 'off');
            app.FiltraUIFigure.Name = 'Filtra';
            app.FiltraUIFigure.CloseRequestFcn = createCallbackFcn(app, @FiltraUIFigureCloseRequest, true);
            app.MarkerEditFieldLabel = uilabel(app.FiltraUIFigure);
            app.MarkerEditFieldLabel.Text = 'Marker';
            app.MarkerEditField = uieditfield(app.FiltraUIFigure, 'numeric');
            app.MarkerEditField.RoundFractionalValues = 'on';
            app.MinSliderLabel = uilabel(app.FiltraUIFigure);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.FiltraUIFigure);
            app.MinSlider.MajorTicks = [0 20 40 60 80 100];
            app.MinSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.MinSlider.ValueChangingFcn = createCallbackFcn(app, @MinSliderValueChanging, true);
            app.MinSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.MaxSliderLabel = uilabel(app.FiltraUIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.FiltraUIFigure);
            app.MaxSlider.MajorTicks = [0 20 40 60 80 100];
            app.MaxSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.MaxSlider.ValueChangingFcn = createCallbackFcn(app, @MaxSliderValueChanging, true);
            app.MaxSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.TimestampLabel = uilabel(app.FiltraUIFigure);
            app.TimestampLabel.Text = 'Timestamp';
            app.minTmField = uieditfield(app.FiltraUIFigure, 'numeric');
            app.minTmField.Limits = [0 Inf];
            app.minTmField.RoundFractionalValues = 'on';
            app.minTmField.ValueDisplayFormat = '%u';
            app.minTmField.ValueChangedFcn = createCallbackFcn(app, @minTmFieldValueChanged, true);
            app.maxTmField = uieditfield(app.FiltraUIFigure, 'numeric');
            app.maxTmField.RoundFractionalValues = 'on';
            app.maxTmField.ValueDisplayFormat = '%u';
            app.maxTmField.ValueChangedFcn = createCallbackFcn(app, @maxTmFieldValueChanged, true);
            app.SalvaButton = uibutton(app.FiltraUIFigure, 'push');
            app.SalvaButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaButtonPushed, true);
            app.SalvaButton.Text = 'Salva';
            app.ResetButton = uibutton(app.FiltraUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.FiltraUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filtra(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FiltraUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FiltraUIFigure)
        end
    end
end