classdef grafico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraficoUIFigure       matlab.ui.Figure
        GridLayout2           matlab.ui.container.GridLayout
        UIAxes                matlab.ui.control.UIAxes
        PulisciassiButton     matlab.ui.control.Button
        Switch                matlab.ui.control.Switch
        SOVRAPPOSIZIONELabel  matlab.ui.control.Label
        PlotButton            matlab.ui.control.Button
        Tree                  matlab.ui.container.Tree
        Node                  matlab.ui.container.TreeNode
        Node2                 matlab.ui.container.TreeNode
        Node_2                matlab.ui.container.TreeNode
        TempodiavvioLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        overlay_n=[];
        overlay_l=[];
        collection
        filterApp
    end
    
    properties (Access = public)
        markerFlt
        minTmFlt
        maxTmFlt
        database=struct;
    end
    
    
    methods (Access = private)
        
        function overlayed(app) % takes in input an array with selected node a prints the diagram
            %EVITARE DUPLICAZIONI
            app.overlay_l
            app.overlay_n
            dim=size(app.overlay_l)
            lght=max(app.overlay_l)
            for i=1:dim
                switch app.overlay_n(i)
                    case 'Inverter right'
                        
                    case 'data1_r'
                        
                    case 'data2_r'
                        
                    case 'Inverter left'
                        
                    case 'data1_l'
                        
                    case 'data2_l'
                        
                    case 'Bms_hv'
                        
                    case 'temperature'
                        
                    case 'max_thv'
                        
                    case 'min_thv'
                        
                    case 'average_thv'
                        
                    case 'voltage'
                        
                    case 'max_vhv'
                        
                    case 'min_vhv'
                        
                    case 'average_vhv'
                        
                    case 'current'
                        
                    case 'current_c'
                        
                    case 'pow'
                        
                    case 'Bms_lv'
                        
                    case 'voltage_lv'
                        
                    case 'temperature_lv'
                        
                    case 'Imu_gyro'
                        
                    case 'x_g'
                        
                    case 'y_g'
                        
                    case 'z_g'
                        
                    case 'Imu_accel'
                        
                    case 'x_a'
                        
                    case 'y_a'
                        
                    case 'z_a'
                        
                    case 'Front_wheels_encoder'
                        
                    case 'speed'
                        
                    case 'speedms'
                        
                    case 'Steering_wheel_encoder'
                        
                    case 'Distance'
                        
                    case 'meters'
                        
                    case 'rotation'
                        
                    case 'angle'
                        
                    case 'clock_period'
                        
                    case "Throttle"
                        plot(app.UIAxes,linspace(1,lght,app.overlay_l(i)),app.tblThrottle{:,5})
                        hold(app.UIAxes,"on")
                    case "Brake"
                        plot(app.UIAxes,linspace(1,lght,app.overlay_l(i)),app.tblBrake{:,5})
                        hold(app.UIAxes,"on")
                end
            end
            legend(app.UIAxes,app.overlay_n);
        end
        
        function filtra(app)
            uialert(app.GraficoUIFigure,'Filtro impostato','Yee')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, origin, collection_db, fileName, save_v, sessionName)
            app.CallingApp = mainapp;
            if origin=="local"
                load(fileName,'-mat','tabledata');
                dimTbl=size(tabledata);
                str="";
                for n=1:dimTbl
                    var=char(tabledata{n,1});
                    str=strcat(str,"'",var,"',");
                end
                cmd=strcat("load(fileName,'-mat',",str,"'sessionName')");
                eval(cmd);
            else
                %open exception log file in write mode (overwrite the previous version)
                fid = fopen('expt_log.txt', 'w');
                if fid == -1
                    disp('Cannot open log file.');
                end
                tabledata=readtable('output.csv','PreserveVariableNames',true); %import structure table from output of main.js
                dim=size(collection_db);
                dimTbl=size(tabledata);
                for i=1:dim(1,1) %iteration over collection rows
                    for l=1:dimTbl(1,1) %iteration over structure rows
                        var=char(tabledata{l,1});
                        route=char(tabledata{l,2});
                        %check if the document element has a value field
                        if char(tabledata{l,3})==""
                            str=strcat(var,"=[",var,",[collection_db(i).",route,"]];");
                            str2=strcat(var,"=[];");
                            eval(str2);
                            try
                                eval(str);
                            catch ME
                                fprintf(fid, '[%s]: %s\n%s\n\n', datestr(now, 0), getReport(ME,'basic'),str);
                            end
                        else
                            %declare temporary variable to store data of the
                            %upper level
                            str=strcat(var,"_tmp=[];",var,"_tmp=[",var,"_tmp,[collection_db(i).",route,"]];");
                            str2=strcat(var,"_tmp=[];");
                            eval(str2);
                            try
                                eval(str);
                            catch ME
                                fprintf(fid, '[%s]: %s\n%s\n\n', datestr(now, 0), getReport(ME,'basic'),str);
                            end
                            str=strcat("size(",var,"_tmp);");
                            dim2=eval(str);
                            str=strcat(var,"=[];");
                            eval(str);
                            appendix=char(tabledata{l,3});
                            for j=1:dim2(1,2) %iterate over value field
                                str=strcat(var,"=[",var,",[",var,"_tmp(j).",appendix,"]];");
                                try
                                    eval(str);
                                catch ME
                                    fprintf(fid, '[%s]: %s\n%s\n\n', datestr(now, 0), getReport(ME,'basic'),str);
                                end
                            end
                        end
                    end
                end
                fclose(fid); %close exception log file
                if save_v==1
                    str="";
                    for n=1:dimTbl
                        var=char(tabledata{n,1});
                        str=strcat(str,"'",var,"',");
                    end
                    cmd=strcat("save(sessionName,'sessionName','dim',",str,"'tabledata')");
                    eval(cmd);
                end
            end
            %tree generation
            firstLayer=[];
            secondLayer=[];
            thirdLayer=[];
            fourthLayer=[];
            for i=1:dimTbl(1,1) %iteration over structure rows
                var=char(tabledata{i,1});
                splitVar=split(var,'__');
                strDim=size(splitVar);
                
                %Annidato fino a un massimo di 4 livelli
                %TODO implementare con colonne di un singolo vettore o
                %implementare con stringhe dichiarate all'inizio (ridurre
                %ripetizione codice
                %(matrice)
                
                for k=1:strDim(1,1) %iteration over route elements
                    if k==1
                        if ~any(strcmp(firstLayer,char(splitVar(k)))) %check if the node already exists
                            firstLayer=[firstLayer,splitVar(k)]; %if not add the name to an array for future checks
                            if k==strDim(1,1) %if it is the bottom level save tha value in the node data
                                str=strcat("app.database.",char(splitVar(k)),"= uitreenode(app.Tree); " + ...
                                    "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"'; " + ...
                                    "app.database.",char(splitVar(k)),".NodeData = ",char(tabledata{i,1}));
                                eval(str);
                            else
                                str=strcat("app.database.",char(splitVar(k)),"= uitreenode(app.Tree); " + ...
                                    "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"';");
                                eval(str);
                            end
                        elseif k==strDim(1,1)
                            str=strcat("app.database.",char(splitVar(k)),"= uitreenode(app.Tree); " + ...
                                "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"'; " + ...
                                "app.database.",char(splitVar(k)),".NodeData = ",char(tabledata{i,1}));
                            eval(str);
                        end
                    elseif k==2
                        if ~any(strcmp(secondLayer,char(splitVar(k))))
                            secondLayer=[secondLayer,splitVar(k)];
                            if k==strDim(1,1)
                                str=strcat("app.database.",char(splitVar(k)),"= uitreenode(app.database.",char(splitVar(k-1)),"); " + ...
                                    "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"'; " + ...
                                    "app.database.",char(splitVar(k)),".NodeData = ",char(tabledata{i,1}));
                                eval(str);
                            else
                                str=strcat("app.database.",char(splitVar(k)),"= uitreenode(app.database.",char(splitVar(k-1)),"); " + ...
                                    "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"';");
                                eval(str);
                            end
                        elseif k==strDim(1,1)
                            str=strcat("app.database(:).",char(splitVar(k)),"= uitreenode(app.database.",char(splitVar(k-1)),"); " + ...
                                "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"';" + ...
                                "app.database.",char(splitVar(k)),".NodeData = ",char(tabledata{i,1}));
                            eval(str);
                        end
                    elseif k==3
                        if ~any(strcmp(thirdLayer,char(splitVar(k))))
                            thirdLayer=[thirdLayer,splitVar(k)];
                            if k==strDim(1,1)
                                str=strcat("app.database.",char(splitVar(k)),"= uitreenode(app.database.",char(splitVar(k-1)),"); " + ...
                                    "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"'; " + ...
                                    "app.database.",char(splitVar(k)),".NodeData = ",char(tabledata{i,1}));
                                eval(str);
                            else
                                str=strcat("app.database.",char(splitVar(k)),"= uitreenode(app.database.",char(splitVar(k-1)),"); " + ...
                                    "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"';");
                                eval(str);
                            end
                        elseif k==strDim(1,1)
                            str=strcat("app.database.",char(splitVar(k)),"= uitreenode(app.database.",char(splitVar(k-1)),"); " + ...
                                "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"'; " + ...
                                "app.database.",char(splitVar(k)),".NodeData = ",char(tabledata{i,1}));
                            eval(str);
                        end
                    elseif k==4
                        if ~any(strcmp(fourthLayer,char(splitVar(k))))
                            fourthLayer=[fourthLayer,splitVar(k)];
                            if k==strDim(1,1)
                                str=strcat("app.database.",char(splitVar(k)),"= uitreenode(app.database.",char(splitVar(k-1)),"); " + ...
                                    "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"'; " + ...
                                    "app.database.",char(splitVar(k)),".NodeData = ",char(tabledata{i,1}));
                                eval(str);
                            else
                                str=strcat("app.database.",char(splitVar(k)),"= uitreenode(app.database.",char(splitVar(k-1)),"); " + ...
                                    "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"';");
                                eval(str);
                            end
                        elseif k==strDim(1,1)
                            str=strcat("app.database.",char(splitVar(k)),"= uitreenode(app.database.",char(splitVar(k-1)),"); " + ...
                                "app.database.",char(splitVar(k)),".Text = '",char(splitVar(k)),"'; " + ...
                                "app.database.",char(splitVar(k)),".NodeData = ",char(tabledata{i,1}));
                            eval(str);
                        end
                    end
                end
            end
        end

        % Button pushed function: PulisciassiButton
        function PulisciassiButtonPushed(app, event)
            cla(app.UIAxes);
            app.Tree.SelectedNodes=[];
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes);
            app.UIAxes.YLim=[-inf inf];
            minV=0;
            maxV=0;
            dimArray=[];
            legendArray=[];
            selectedElem=app.Tree.SelectedNodes;
            dim=size(selectedElem);
            name="";
            %iteration to get dimensions (used to adapt data) and names
            for i=1:dim
                dimension=size(selectedElem(i).NodeData);
                dimArray=[dimArray, dimension(2)];
                %legend generation
                try
                    name=strcat(selectedElem(i).Text,"-",selectedElem(i).Parent.Text); %try if the node has parent
                catch ME
                    name=selectedElem(i).Text;
                end
                legendArray=[legendArray, {name}]
            end
            maxWidth=max(dimArray);
            %set first parameters for max and min value
            minV=min(selectedElem(1).NodeData);
            maxV=max(selectedElem(1).NodeData);
            %iteration to plot data
            for i=1:dim
                plot(app.UIAxes,linspace(0,maxWidth-1,dimArray(i)),selectedElem(i).NodeData);
                hold(app.UIAxes,"on")
                if min(selectedElem(i).NodeData)<minV
                    minV=min(selectedElem(i).NodeData);
                end
                if max(selectedElem(i).NodeData)>maxV
                    maxV=max(selectedElem(i).NodeData);
                end
            end
            app.UIAxes.XLim=[0 maxWidth-1];
            minV=minV-1;
            maxV=maxV+1;
            app.UIAxes.YLim=[minV maxV];
            app.UIAxes.YLabel.String="";
            app.UIAxes.XLabel.String="Tempo";
            legend(app.UIAxes,legendArray);
        end

        % Close request function: GraficoUIFigure
        function GraficoUIFigureCloseRequest(app, event)
            delete(app.filterApp)
            delete(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged2(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if app.Switch.Value=="Off"
                cla(app.UIAxes);
                dim=size(selectedNodes.NodeData);
                if dim(1,2)>1
                    plot(app.UIAxes,0:dim(1,2)-1,selectedNodes.NodeData)
                    app.UIAxes.XLim=[0 dim(1,2)-1];
                    maxV=1.25*max(selectedNodes.NodeData);
                    minV=min(selectedNodes.NodeData);
                    if minV==0 && maxV==0
                        app.UIAxes.YLim=[-1 1];
                    elseif minV>0
                        app.UIAxes.YLim=[0 maxV];
                    elseif -minV>maxV
                        app.UIAxes.YLim=[1.25*minV -1.25*minV];
                    else
                        app.UIAxes.YLim=[-maxV maxV];
                    end
                    app.UIAxes.Title.String="";
                    app.UIAxes.YLabel.String=selectedNodes.Text;
                    app.UIAxes.XLabel.String="Tempo";
                end
                legend(app.UIAxes,selectedNodes.Text);
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            cla(app.UIAxes);
            app.Tree.SelectedNodes=[];
            value = app.Switch.Value;
            if value=="On"
                app.Tree.Multiselect='on';
                uialert(app.GraficoUIFigure,"Usa il tasto ctrl per selezionare gli elementi che intendi visualizzare, per visulizzare il grafico della selezione utilizza il pulsante plot in basso a sinistra","Selezione multipla",'icon','info');
                app.PlotButton.Enable=true;
            else
                app.Tree.Multiselect='off';
                app.PlotButton.Enable=false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraficoUIFigure = uifigure('Visible', 'off');
            app.GraficoUIFigure.Name = 'Grafico';
            app.GraficoUIFigure.CloseRequestFcn = createCallbackFcn(app, @GraficoUIFigureCloseRequest, true);
            app.GridLayout2 = uigridlayout(app.GraficoUIFigure);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PulisciassiButton = uibutton(app.GridLayout2, 'push');
            app.PulisciassiButton.ButtonPushedFcn = createCallbackFcn(app, @PulisciassiButtonPushed, true);
            app.PulisciassiButton.Text = 'Pulisci assi';
            app.Switch = uiswitch(app.GridLayout2, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.SOVRAPPOSIZIONELabel = uilabel(app.GridLayout2);
            app.SOVRAPPOSIZIONELabel.Text = 'SOVRAPPOSIZIONE';
            app.PlotButton = uibutton(app.GridLayout2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.Tree = uitree(app.GridLayout2);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged2, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Tree);
            app.Node2.Text = 'Node2';
            app.Node_2 = uitreenode(app.Node2);
            app.Node_2.Text = 'Node';
            app.TempodiavvioLabel = uilabel(app.GraficoUIFigure);
            app.TempodiavvioLabel.Text = 'Tempo di avvio:';
            % Show the figure after all components are created
            app.GraficoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grafico(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraficoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraficoUIFigure)
        end
    end
end